classdef app_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BaseDatosMotoresUIFigure     matlab.ui.Figure
        UITable                      matlab.ui.control.Table
        DatosMotorPanel              matlab.ui.container.Panel
        FabricanteEditFieldLabel     matlab.ui.control.Label
        FabricanteEditField          matlab.ui.control.EditField
        PotenciaNominalSpinnerLabel  matlab.ui.control.Label
        PotenciaNominalSpinner       matlab.ui.control.Spinner
        TensionNominalSpinnerLabel   matlab.ui.control.Label
        TensionNominalSpinner        matlab.ui.control.Spinner
        ComentarioTextAreaLabel      matlab.ui.control.Label
        ComentarioTextArea           matlab.ui.control.TextArea
        GuardarButton                matlab.ui.control.Button
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            motoresdb = matfile('motoresdb.mat', 'Writable', false);
            app.UITable.Data = motoresdb.motoresdb;
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            motoresdb = matfile('motoresdb.mat', 'Writable',true);
            
            fabricante = app.FabricanteEditField.Value;
            Pn = app.PotenciaNominalSpinner.Value;
            Un = app.TensionNominalSpinner.Value;
            comentario = char(app.ComentarioTextArea.Value);            
            
            if isempty(motoresdb.motoresdb)
                motoresdb.motoresdb = {fabricante, Pn, Un, comentario};
            else
                motoresdb.motoresdb = vertcat(motoresdb.motoresdb, {fabricante, Pn, Un, comentario});
            end
            
            app.UITable.Data = motoresdb.motoresdb;
            
            app.FabricanteEditField.Value = '';
            app.PotenciaNominalSpinner.Value = 0;
            app.TensionNominalSpinner.Value = 0;
            app.ComentarioTextArea.Value = '';
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            datos = event.Source.Data(event.Indices(1), :);
            
            app.FabricanteEditField.Value = char(datos(1));
            app.PotenciaNominalSpinner.Value = cell2mat(datos(2));
            app.TensionNominalSpinner.Value = cell2mat(datos(3));
            app.ComentarioTextArea.Value = char(datos(4));
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BaseDatosMotoresUIFigure = uifigure;
            app.BaseDatosMotoresUIFigure.Name = 'Base Datos Motores';
            app.UITable = uitable(app.BaseDatosMotoresUIFigure);
            app.UITable.ColumnName = {'Fabricante'; 'Potencia Nominal'; 'Tensión Nominal'; 'Comentario'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.DatosMotorPanel = uipanel(app.BaseDatosMotoresUIFigure);
            app.DatosMotorPanel.Title = 'Datos Motor';
            app.FabricanteEditFieldLabel = uilabel(app.DatosMotorPanel);
            app.FabricanteEditFieldLabel.Text = 'Fabricante';
            app.FabricanteEditField = uieditfield(app.DatosMotorPanel, 'text');
            app.PotenciaNominalSpinnerLabel = uilabel(app.DatosMotorPanel);
            app.PotenciaNominalSpinnerLabel.Text = 'Potencia Nominal';
            app.PotenciaNominalSpinner = uispinner(app.DatosMotorPanel);
            app.TensionNominalSpinnerLabel = uilabel(app.DatosMotorPanel);
            app.TensionNominalSpinnerLabel.Text = 'Tension Nominal';
            app.TensionNominalSpinner = uispinner(app.DatosMotorPanel);
            app.ComentarioTextAreaLabel = uilabel(app.DatosMotorPanel);
            app.ComentarioTextAreaLabel.Text = 'Comentario';
            app.ComentarioTextArea = uitextarea(app.DatosMotorPanel);
            app.GuardarButton = uibutton(app.DatosMotorPanel, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app_3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BaseDatosMotoresUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BaseDatosMotoresUIFigure)
        end
    end
end