classdef app_5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelectorescontrolUIFigure  matlab.ui.Figure
        DiscretoKnobLabel          matlab.ui.control.Label
        DiscretoKnob               matlab.ui.control.DiscreteKnob
        ContinuoKnobLabel          matlab.ui.control.Label
        ContinuoKnob               matlab.ui.control.Knob
        ValorEditFieldLabel        matlab.ui.control.Label
        ValorEditField             matlab.ui.control.NumericEditField
    end


    methods (Access = private)
    
        function results = func(app)
            
        end
        
    end


    methods (Access = private)

        % Value changed function: ContinuoKnob
        function ContinuoKnobValueChanged(app, event)
            value = app.ContinuoKnob.Value;
            
            if value < 30
                val = 'Off';
            elseif value < 60
                val = 'Low';
            elseif value < 100
                val = 'Medium';
            else
                val = 'High';
            end
            
            app.DiscretoKnob.Value = val;
            app.ValorEditField.Value = value;
        end

        % Value changed function: DiscretoKnob
        function DiscretoKnobValueChanged(app, event)
            value = app.DiscretoKnob.Value;
            
            switch value
                case 'Low'
                    val = 30;
                case 'Medium'
                    val = 60;
                case 'High'
                    val = 100;
                otherwise
                    val = 0;
            end
            
            app.ContinuoKnob.Value = val;
            app.ValorEditField.Value = val;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelectorescontrolUIFigure = uifigure;
            app.SelectorescontrolUIFigure.Name = 'Selectores control';
            app.DiscretoKnobLabel = uilabel(app.SelectorescontrolUIFigure);
            app.DiscretoKnobLabel.Text = 'Discreto';
            app.DiscretoKnob = uiknob(app.SelectorescontrolUIFigure, 'discrete');
            app.DiscretoKnob.ValueChangedFcn = createCallbackFcn(app, @DiscretoKnobValueChanged, true);
            app.ContinuoKnobLabel = uilabel(app.SelectorescontrolUIFigure);
            app.ContinuoKnobLabel.Text = 'Continuo';
            app.ContinuoKnob = uiknob(app.SelectorescontrolUIFigure, 'continuous');
            app.ContinuoKnob.ValueChangedFcn = createCallbackFcn(app, @ContinuoKnobValueChanged, true);
            app.ValorEditFieldLabel = uilabel(app.SelectorescontrolUIFigure);
            app.ValorEditFieldLabel.Text = 'Valor';
            app.ValorEditField = uieditfield(app.SelectorescontrolUIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = app_5

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SelectorescontrolUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelectorescontrolUIFigure)
        end
    end
end