classdef app_6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InterruptoresUIFigure    matlab.ui.Figure
        Interruptor1SwitchLabel  matlab.ui.control.Label
        Interruptor1Switch       matlab.ui.control.Switch
        Interruptor2SwitchLabel  matlab.ui.control.Label
        Interruptor2Switch       matlab.ui.control.RockerSwitch
        Interruptor3SwitchLabel  matlab.ui.control.Label
        Interruptor3Switch       matlab.ui.control.ToggleSwitch
        Habilitacion1LampLabel   matlab.ui.control.Label
        Habilitacion1Lamp        matlab.ui.control.Lamp
        Habilitacion2LampLabel   matlab.ui.control.Label
        Habilitacion2Lamp        matlab.ui.control.Lamp
        Habilitacion3LampLabel   matlab.ui.control.Label
        Habilitacion3Lamp        matlab.ui.control.Lamp
        Opcion1SwitchLabel       matlab.ui.control.Label
        Opcion1Switch            matlab.ui.control.Switch
        Opcion2SwitchLabel       matlab.ui.control.Label
        Opcion2Switch            matlab.ui.control.Switch
        Opcion3SwitchLabel       matlab.ui.control.Label
        Opcion3Switch            matlab.ui.control.Switch
        LeerOpcionesButton       matlab.ui.control.Button
        OpcionesLamp             matlab.ui.control.Lamp
    end


    methods (Access = private)
    
        function results = estado_binario(valor)
            switch valor
                case 'On'
                    results = 1;
                otherwise
                    results = 0;
            end
        end
        
    end


    methods (Access = private)

        % Value changed function: Interruptor1Switch
        function Interruptor1SwitchValueChanged(app, event)
            value = app.Interruptor1Switch.Value;
            
            switch value
                case 'Inicio'
                    app.Habilitacion1Lamp.Color = [0 1 0];
                otherwise
                    app.Habilitacion1Lamp.Color = [1 0 0];
            end
        end

        % Value changed function: Interruptor2Switch
        function Interruptor2SwitchValueChanged(app, event)
            value = app.Interruptor2Switch.Value;
            
            switch value
                case 'Encender'
                    app.Habilitacion2Lamp.Color = 'g';
                otherwise
                    app.Habilitacion2Lamp.Color = 'r';
            end
        end

        % Value changed function: Interruptor3Switch
        function Interruptor3SwitchValueChanged(app, event)
            value = app.Interruptor3Switch.Value;
            
            switch value
                case '1'
                    app.Habilitacion3Lamp.Color = [0 1 0];
                otherwise
                    app.Habilitacion3Lamp.Color = [1 0 0];
            end
        end

        % Button pushed function: LeerOpcionesButton
        function LeerOpcionesButtonPushed(app, event)
            switch app.Opcion1Switch.Value
                case 'On'
                    value1 = 1;
                otherwise
                    value1 = 0;
            end
            
            switch app.Opcion2Switch.Value
                case 'On'
                    value2 = 1;
                otherwise
                    value2 = 0;
            end
            
            switch app.Opcion3Switch.Value
                case 'On'
                    value3 = 1;
                otherwise
                    value3 = 0;
            end
            
            color = [value1 value2 value3];
            
            app.OpcionesLamp.Color = color;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InterruptoresUIFigure = uifigure;
            app.InterruptoresUIFigure.Name = 'Interruptores';
            app.Interruptor1SwitchLabel = uilabel(app.InterruptoresUIFigure);
            app.Interruptor1SwitchLabel.Text = 'Interruptor 1';
            app.Interruptor1Switch = uiswitch(app.InterruptoresUIFigure, 'slider');
            app.Interruptor1Switch.Items = {'Parar', 'Inicio'};
            app.Interruptor1Switch.ValueChangedFcn = createCallbackFcn(app, @Interruptor1SwitchValueChanged, true);
            app.Interruptor1Switch.Value = 'Parar';
            app.Interruptor2SwitchLabel = uilabel(app.InterruptoresUIFigure);
            app.Interruptor2SwitchLabel.Text = 'Interruptor 2';
            app.Interruptor2Switch = uiswitch(app.InterruptoresUIFigure, 'rocker');
            app.Interruptor2Switch.Items = {'Apagar', 'Encender'};
            app.Interruptor2Switch.ValueChangedFcn = createCallbackFcn(app, @Interruptor2SwitchValueChanged, true);
            app.Interruptor2Switch.Value = 'Apagar';
            app.Interruptor3SwitchLabel = uilabel(app.InterruptoresUIFigure);
            app.Interruptor3SwitchLabel.Text = 'Interruptor 3';
            app.Interruptor3Switch = uiswitch(app.InterruptoresUIFigure, 'toggle');
            app.Interruptor3Switch.Items = {'0', '1'};
            app.Interruptor3Switch.ValueChangedFcn = createCallbackFcn(app, @Interruptor3SwitchValueChanged, true);
            app.Interruptor3Switch.Value = '0';
            app.Habilitacion1LampLabel = uilabel(app.InterruptoresUIFigure);
            app.Habilitacion1LampLabel.Text = 'Habilitacion 1';
            app.Habilitacion1Lamp = uilamp(app.InterruptoresUIFigure);
            app.Habilitacion2LampLabel = uilabel(app.InterruptoresUIFigure);
            app.Habilitacion2LampLabel.Text = 'Habilitacion 2';
            app.Habilitacion2Lamp = uilamp(app.InterruptoresUIFigure);
            app.Habilitacion3LampLabel = uilabel(app.InterruptoresUIFigure);
            app.Habilitacion3LampLabel.Text = 'Habilitacion 3';
            app.Habilitacion3Lamp = uilamp(app.InterruptoresUIFigure);
            app.Opcion1SwitchLabel = uilabel(app.InterruptoresUIFigure);
            app.Opcion1SwitchLabel.Text = 'Opcion 1';
            app.Opcion1Switch = uiswitch(app.InterruptoresUIFigure, 'slider');
            app.Opcion2SwitchLabel = uilabel(app.InterruptoresUIFigure);
            app.Opcion2SwitchLabel.Text = 'Opcion 2';
            app.Opcion2Switch = uiswitch(app.InterruptoresUIFigure, 'slider');
            app.Opcion3SwitchLabel = uilabel(app.InterruptoresUIFigure);
            app.Opcion3SwitchLabel.Text = 'Opcion 3';
            app.Opcion3Switch = uiswitch(app.InterruptoresUIFigure, 'slider');
            app.LeerOpcionesButton = uibutton(app.InterruptoresUIFigure, 'push');
            app.LeerOpcionesButton.ButtonPushedFcn = createCallbackFcn(app, @LeerOpcionesButtonPushed, true);
            app.LeerOpcionesButton.Text = 'Leer Opciones';
            app.OpcionesLamp = uilamp(app.InterruptoresUIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = app_6

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InterruptoresUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InterruptoresUIFigure)
        end
    end
end