classdef app_7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AxesUIFigure              matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        GraficarButton            matlab.ui.control.Button
        XmaxSpinnerLabel          matlab.ui.control.Label
        XmaxSpinner               matlab.ui.control.Spinner
        XminSpinnerLabel          matlab.ui.control.Label
        XminSpinner               matlab.ui.control.Spinner
        aSpinnerLabel             matlab.ui.control.Label
        aSpinner                  matlab.ui.control.Spinner
        hSpinnerLabel             matlab.ui.control.Label
        hSpinner                  matlab.ui.control.Spinner
        kSpinnerLabel             matlab.ui.control.Label
        kSpinner                  matlab.ui.control.Spinner
        yaxh2kLabel               matlab.ui.control.Label
        DominioLabel              matlab.ui.control.Label
        ImagenLabel               matlab.ui.control.Label
        ZoomButton                matlab.ui.control.Button
        PanButton                 matlab.ui.control.Button
        OffButton                 matlab.ui.control.Button
        EstiloLineaDropDownLabel  matlab.ui.control.Label
        EstiloLineaDropDown       matlab.ui.control.DropDown
    end


    methods (Access = private)
    
        function graficar(app)
            xmin = app.XminSpinner.Value;
            xmax = app.XmaxSpinner.Value;
            if xmin < xmax
                x = xmin: xmax;
              
                a = app.aSpinner.Value;
                h = app.hSpinner.Value;
                k = app.kSpinner.Value;
                yC = a * (x - h) .^ 2 + k;
                
                switch app.EstiloLineaDropDown.Value
                    case 'Solid'
                        estilo_linea = '-';
                    case 'Dashed'
                        estilo_linea = '--';
                    case 'Dotted'
                        estilo_linea = ':';
                    case 'Dash-dotted'
                        estilo_linea = '-.';
                    otherwise
                        estilo_linea = 'none';
                end
                
                plot(app.UIAxes, x, yC, estilo_linea)
                set(app.UIAxes, 'xaxislocation', 'origin' )
                set(app.UIAxes, 'yaxislocation', 'origin' )
            else
                errordlg('Error en la asignación de los límites del dominio', 'Error')
            end
        end
        
    end


    methods (Access = private)

        % Value changed function: EstiloLineaDropDown
        function EstiloLineaDropDownValueChanged(app, event)
            app.graficar();
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            app.graficar();
        end

        % Button pushed function: OffButton
        function OffButtonPushed(app, event)
            zoom(app.UIAxes, 'off');
            pan(app.UIAxes, 'off');
            reset(app.UIAxes);
            grid(app.UIAxes);
        end

        % Button pushed function: PanButton
        function PanButtonPushed(app, event)
            pan(app.UIAxes, 'on');
        end

        % Button pushed function: ZoomButton
        function ZoomButtonPushed(app, event)
            zoom(app.UIAxes, 'on');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AxesUIFigure = uifigure;
            app.AxesUIFigure.Name = 'Axes';
            app.UIAxes = uiaxes(app.AxesUIFigure);
            title(app.UIAxes, 'Graficas')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.GraficarButton = uibutton(app.AxesUIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.XmaxSpinnerLabel = uilabel(app.AxesUIFigure);
            app.XmaxSpinnerLabel.Text = 'Xmax';
            app.XmaxSpinner = uispinner(app.AxesUIFigure);
            app.XmaxSpinner.Value = 100;
            app.XminSpinnerLabel = uilabel(app.AxesUIFigure);
            app.XminSpinnerLabel.Text = 'Xmin';
            app.XminSpinner = uispinner(app.AxesUIFigure);
            app.XminSpinner.Value = -100;
            app.aSpinnerLabel = uilabel(app.AxesUIFigure);
            app.aSpinnerLabel.Text = 'a';
            app.aSpinner = uispinner(app.AxesUIFigure);
            app.aSpinner.Limits = [0.01 Inf];
            app.aSpinner.Value = 1;
            app.hSpinnerLabel = uilabel(app.AxesUIFigure);
            app.hSpinnerLabel.Text = 'h';
            app.hSpinner = uispinner(app.AxesUIFigure);
            app.kSpinnerLabel = uilabel(app.AxesUIFigure);
            app.kSpinnerLabel.Text = 'k';
            app.kSpinner = uispinner(app.AxesUIFigure);
            app.yaxh2kLabel = uilabel(app.AxesUIFigure);
            app.yaxh2kLabel.Text = 'y=a·(x-h)^2+k';
            app.DominioLabel = uilabel(app.AxesUIFigure);
            app.DominioLabel.Text = 'Dominio';
            app.ImagenLabel = uilabel(app.AxesUIFigure);
            app.ImagenLabel.Text = 'Imagen';
            app.ZoomButton = uibutton(app.AxesUIFigure, 'push');
            app.ZoomButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomButtonPushed, true);
            app.ZoomButton.Text = 'Zoom';
            app.PanButton = uibutton(app.AxesUIFigure, 'push');
            app.PanButton.ButtonPushedFcn = createCallbackFcn(app, @PanButtonPushed, true);
            app.PanButton.Text = 'Pan';
            app.OffButton = uibutton(app.AxesUIFigure, 'push');
            app.OffButton.ButtonPushedFcn = createCallbackFcn(app, @OffButtonPushed, true);
            app.OffButton.Text = 'Off';
            app.EstiloLineaDropDownLabel = uilabel(app.AxesUIFigure);
            app.EstiloLineaDropDownLabel.Text = 'Estilo Linea';
            app.EstiloLineaDropDown = uidropdown(app.AxesUIFigure);
            app.EstiloLineaDropDown.Items = {'Solid', 'Dashed', 'Dotted', 'Dash-dotted'};
            app.EstiloLineaDropDown.ValueChangedFcn = createCallbackFcn(app, @EstiloLineaDropDownValueChanged, true);
            app.EstiloLineaDropDown.Value = 'Solid';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app_7

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AxesUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AxesUIFigure)
        end
    end
end